classdef mask_output_helper_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ModulateMaskPowerCheckBox  matlab.ui.control.CheckBox
        CancelButton               matlab.ui.control.Button
        OkayButton                 matlab.ui.control.Button
        ReverseSliceOrderCheckBox  matlab.ui.control.CheckBox
        IncludeCombinedCheckBox    matlab.ui.control.CheckBox
        YDisplacementSpinner       matlab.ui.control.Spinner
        YDisplacementSpinnerLabel  matlab.ui.control.Label
        XDisplacementSpinner       matlab.ui.control.Spinner
        XDisplacementSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        callingApp
        params
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, params)
            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowState = 'normal';
            app.callingApp = callingApp;
            app.params = params;
            app.IncludeCombinedCheckBox.Enable = params.splitChannels && (params.channels > 1);
            app.ReverseSliceOrderCheckBox.Enable = params.volume;
            app.ModulateMaskPowerCheckBox.Enable = params.volume;
            helper_apps.centerApp(app,callingApp);
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: OkayButton
        function okay(app, event)
            app.params.combine = app.IncludeCombinedCheckBox.Value;
            app.params.reverse = app.ReverseSliceOrderCheckBox.Value;
            app.params.xDisp = app.XDisplacementSpinner.Value;
            app.params.yDisp = app.YDisplacementSpinner.Value;
            app.params.maskPower = app.ModulateMaskPowerCheckBox.Value;
            app.callingApp.outputClbk(app.params);
        end

        % Callback function: CancelButton, UIFigure
        function cancel(app, event)
            app.callingApp.closeOutput();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'AES Mask Properties';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'thumbnail_mask_app.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @cancel, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.XDisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.XDisplacementSpinnerLabel.Text = 'X Displacement:';
            app.XDisplacementSpinner = uispinner(app.GridLayout);
            app.XDisplacementSpinner.RoundFractionalValues = 'on';
            app.YDisplacementSpinnerLabel = uilabel(app.GridLayout);
            app.YDisplacementSpinnerLabel.Text = 'Y Displacement:';
            app.YDisplacementSpinner = uispinner(app.GridLayout);
            app.IncludeCombinedCheckBox = uicheckbox(app.GridLayout);
            app.IncludeCombinedCheckBox.Text = 'Include Combined';
            app.ReverseSliceOrderCheckBox = uicheckbox(app.GridLayout);
            app.ReverseSliceOrderCheckBox.Text = 'Reverse Slice Order';
            app.OkayButton = uibutton(app.GridLayout, 'push');
            app.OkayButton.ButtonPushedFcn = createCallbackFcn(app, @okay, true);
            app.OkayButton.Text = 'Okay';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.ModulateMaskPowerCheckBox = uicheckbox(app.GridLayout);
            app.ModulateMaskPowerCheckBox.Text = 'Modulate Mask Power';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mask_output_helper_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end